classdef ArrenbergToolboxUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ArrenbergToolboxUIFigure  matlab.ui.Figure
        TCPIPClientfordatastreamingtovxPyLabel  matlab.ui.control.Label
        DisconnectButton          matlab.ui.control.Button
        PortEditField             matlab.ui.control.NumericEditField
        PortEditFieldLabel        matlab.ui.control.Label
        IPadressEditField         matlab.ui.control.EditField
        IPadressEditFieldLabel    matlab.ui.control.Label
        ConnectButton             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if ArrenbergInitTcpClient(app.IPadressEditField.Value, app.PortEditField.Value)
                set(app.DisconnectButton,'Enable','on');
                set(app.ConnectButton,'Enable','off');
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            if ArrenbergCloseTcpClient()
                set(app.ConnectButton,'Enable','on');
                set(app.DisconnectButton,'Enable','off');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ArrenbergToolboxUIFigure = uifigure('Visible', 'off');
            app.ArrenbergToolboxUIFigure.Name = 'Arrenberg Toolbox';
            app.ConnectButton = uibutton(app.ArrenbergToolboxUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.IPadressEditFieldLabel = uilabel(app.ArrenbergToolboxUIFigure);
            app.IPadressEditFieldLabel.Text = 'IP adress';
            app.IPadressEditField = uieditfield(app.ArrenbergToolboxUIFigure, 'text');
            app.IPadressEditField.Value = '172.25.187.213';
            app.PortEditFieldLabel = uilabel(app.ArrenbergToolboxUIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.ArrenbergToolboxUIFigure, 'numeric');
            app.PortEditField.ValueDisplayFormat = '%.0f';
            app.PortEditField.Value = 55000;
            app.DisconnectButton = uibutton(app.ArrenbergToolboxUIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.TCPIPClientfordatastreamingtovxPyLabel = uilabel(app.ArrenbergToolboxUIFigure);
            app.TCPIPClientfordatastreamingtovxPyLabel.Text = 'TCP/IP Client for data streaming to vxPy';
            % Show the figure after all components are created
            app.ArrenbergToolboxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ArrenbergToolboxUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ArrenbergToolboxUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ArrenbergToolboxUIFigure)
        end
    end
end